classdef default_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MainControlPanel        matlab.ui.container.Panel
        GenerateButton          matlab.ui.control.Button
        InspectAllButton        matlab.ui.control.Button
        DistributeButton        matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        BoardPickerButtonGroup  matlab.ui.container.ButtonGroup
        BoardControlPanel       matlab.ui.container.Panel
        SSHButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        conf Configuration% Description
        selected_i  % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, conf)
            app.conf = conf;
            c = length(conf.Boards);
            g = uigridlayout(app.BoardPickerButtonGroup, [1 c]);
            for i=1:c
                b = uitogglebutton(app.BoardPickerButtonGroup);
                b.Text = ['Board ' num2str(i)];
                b.Position = [10 24*i 80 22];
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.conf.generate
        end

        % Button pushed function: InspectAllButton
        function InspectAllButtonPushed(app, event)
            if ~isempty(app.selected_i)
                app.conf.Boards(app.selected_i).openShell();
            end
        end

        % Button pushed function: DistributeButton
        function DistributeButtonPushed(app, event)
            app.conf.distribute
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.conf.start
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.conf.stop
        end

        % Selection changed function: BoardPickerButtonGroup
        function BoardPickerButtonGroupSelectionChanged(app, event)
            c = app.BoardPickerButtonGroup.SelectedObject.Text;
            app.BoardControlPanel.Title = [c ' control'];
            c = strrep(c, 'Board ','');
            i = str2double(c);
            app.selected_i = i;
        end

        % Button pushed function: SSHButton
        function SSHButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainControlPanel = uipanel(app.UIFigure);
            app.MainControlPanel.Title = 'Main Control';
            app.GenerateButton = uibutton(app.MainControlPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.InspectAllButton = uibutton(app.MainControlPanel, 'push');
            app.InspectAllButton.ButtonPushedFcn = createCallbackFcn(app, @InspectAllButtonPushed, true);
            app.InspectAllButton.Text = 'Inspect All';
            app.DistributeButton = uibutton(app.MainControlPanel, 'push');
            app.DistributeButton.ButtonPushedFcn = createCallbackFcn(app, @DistributeButtonPushed, true);
            app.DistributeButton.Text = 'Distribute';
            app.StartButton = uibutton(app.MainControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MainControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.BoardPickerButtonGroup = uibuttongroup(app.UIFigure);
            app.BoardPickerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoardPickerButtonGroupSelectionChanged, true);
            app.BoardPickerButtonGroup.Title = 'Board Picker';
            app.BoardControlPanel = uipanel(app.UIFigure);
            app.BoardControlPanel.Title = 'Board Control';
            app.SSHButton = uibutton(app.BoardControlPanel, 'push');
            app.SSHButton.ButtonPushedFcn = createCallbackFcn(app, @SSHButtonPushed, true);
            app.SSHButton.Text = 'SSH';
        end
    end

    methods (Access = public)

        % Construct app
        function app = default_gui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end